classdef test_tirem_rev1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        TextArea                matlab.ui.control.TextArea
        Button_SelectSimFolder  matlab.ui.control.Button
        TestTiremLabel          matlab.ui.control.Label
        Rev10Label              matlab.ui.control.Label
    end


    methods (Access = public)


        function accumulate_text(app, message)
            if strcmp(app.TextArea.Value,'')
                message_disp = message;
            else
                message_disp = [app.TextArea.Value(:)', {message}];
            end
            app.TextArea.Value = message_disp;
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)


        end

        % Button pushed function: Button_SelectSimFolder
        function Button_SelectSimFolderPushed(app, event)
            accumulate_text(app,'Starting Sims')

            tic;
            %%%%%%%%%%%%%%%%%%%%%%%%%%%Test to Check ITM/Terrain
            accumulate_text(app,'Line 44')
            NET.addAssembly(fullfile('C:\USGS', 'SEADLib.dll')); %%%%%%Where the SEADLib.dll is located
            accumulate_text(app,'Line 46')

            TxLat = 43.05;
            TxLon = -70.79;
            TxHtm = 30;

            RxLat =  42.3805;
            RxLon = -71.0468;
            RxHtm = 6.0;


            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%Terrain Profile
            accumulate_text(app,'Line 61')
            USGS3Secp = TerrainPcs.USGS;
            CoordTx = TerrainPcs.Geolocation(TxLat,TxLon);
            CoordRx = TerrainPcs.Geolocation(RxLat,RxLon);
            USGS3Secp.TerrainDataPath='C:\USGS';
            Elev = double(USGS3Secp.GetPathElevation(CoordTx,CoordRx,90,true));  %%%%%%%%%This is the "z" equivalent
            accumulate_text(app,'Line 67')

            temp_dist_km=deg2km(distance(TxLat,TxLon,RxLat,RxLon));
            r=linspace(0,temp_dist_km*1000,length(Elev));
            z=Elev;
            FreqHz=3.5e9; %%%%%Hz
            accumulate_text(app,'Line 73')
            tiremSetup('C:\USGS\TIREM5')
            accumulate_text(app,'Line 75')
            [tirem_pl_terrain,tirem_info_terrain]=tirempl(r,z,FreqHz,'TransmitterAntennaHeight',TxHtm,'ReceiverAntennaHeight',RxHtm)
            accumulate_text(app,'Line 77')

            horzcat(tirem_pl_terrain)
            accumulate_text(app,'Line 80')
            accumulate_text(app,strcat('212.21dB ------>',num2str(horzcat(tirem_pl_terrain))))
            accumulate_text(app,'Line 82')

        end

        % Callback function
        function ManualCBSDRadiusCheckBoxValueChanged(app, event)
            tf_manual= app.ManualCBSDRadiusCheckBox.Value;

            if tf_manual==1
                app.CBSDGenerationParametersPanel.Visible='on';
            else
                app.CBSDGenerationParametersPanel.Visible='off';
            end
        end

        % Callback function
        function ComputationSelectButtonGroupSelectionChanged(app, event)
            check_comp(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.Rev10Label = uilabel(app.UIFigure);
            app.Rev10Label.Text = 'Rev 1.0';
            app.TestTiremLabel = uilabel(app.UIFigure);
            app.TestTiremLabel.Text = 'Test Tirem';
            app.Button_SelectSimFolder = uibutton(app.UIFigure, 'push');
            app.Button_SelectSimFolder.ButtonPushedFcn = createCallbackFcn(app, @Button_SelectSimFolderPushed, true);
            app.Button_SelectSimFolder.Text = 'Push to Test';
            app.TextArea = uitextarea(app.UIFigure);
            app.TextArea.Editable = 'off';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = test_tirem_rev1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end